classdef winConfig < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        Folders_Grid             matlab.ui.container.GridLayout
        FolderMapPanel           matlab.ui.container.Panel
        FolderMapGrid            matlab.ui.container.GridLayout
        userPathButton           matlab.ui.control.Image
        userPath                 matlab.ui.control.EditField
        userPathLabel            matlab.ui.control.Label
        DataHubPOSTButton        matlab.ui.control.Image
        DataHubPOST              matlab.ui.control.EditField
        DataHubPOSTLabel         matlab.ui.control.Label
        config_FolderMapLabel    matlab.ui.control.Label
        CustomPlotGrid           matlab.ui.container.GridLayout
        general_FilePanel        matlab.ui.container.Panel
        GridLayout_2             matlab.ui.container.GridLayout
        EncodingLabel            matlab.ui.control.Label
        EncodingDropDown_2       matlab.ui.control.DropDown
        CommandTest1             matlab.ui.control.Button
        ButtonGroup              matlab.ui.container.ButtonGroup
        Button3                  matlab.ui.control.RadioButton
        Button2                  matlab.ui.control.RadioButton
        Button                   matlab.ui.control.RadioButton
        TabGroup                 matlab.ui.container.TabGroup
        Tab                      matlab.ui.container.Tab
        Tab2                     matlab.ui.container.Tab
        Tab3                     matlab.ui.container.Tab
        isdeployedButton         matlab.ui.control.Button
        ctfrootButton            matlab.ui.control.Button
        XButton                  matlab.ui.control.Button
        CommandTest2             matlab.ui.control.Button
        CommandTest3             matlab.ui.control.Button
        Output                   matlab.ui.control.TextArea
        Run                      matlab.ui.control.Button
        Input                    matlab.ui.control.TextArea
        general_FileLabel        matlab.ui.control.Label
        General_Grid             matlab.ui.container.GridLayout
        openAuxiliarApp2Debug    matlab.ui.control.CheckBox
        openAuxiliarAppAsDocked  matlab.ui.control.CheckBox
        versionInfo              matlab.ui.control.Label
        versionInfoRefresh       matlab.ui.control.Image
        versionInfoLabel         matlab.ui.control.Label
        LeftPanelRadioGroup      matlab.ui.container.ButtonGroup
        btnFolder                matlab.ui.control.RadioButton
        btnAnalysis              matlab.ui.control.RadioButton
        btnGeneral               matlab.ui.control.RadioButton
        LeftPanel                matlab.ui.container.Panel
        LeftPanelGrid            matlab.ui.container.GridLayout
        menuUnderline            matlab.ui.control.Image
        menu_ButtonGrid          matlab.ui.container.GridLayout
        menu_ButtonIcon          matlab.ui.control.Image
        menu_ButtonLabel         matlab.ui.control.Label
        toolGrid                 matlab.ui.container.GridLayout
        openDevTools             matlab.ui.control.Image
    end

    
    properties
        %-----------------------------------------------------------------%
        Container
        isDocked = false
        
        mainApp
        rootFolder

        % A função do timer é executada uma única vez após a renderização
        % da figura, lendo arquivos de configuração, iniciando modo de operação
        % paralelo etc. A ideia é deixar o MATLAB focar apenas na criação dos 
        % componentes essenciais da GUI (especificados em "createComponents"), 
        % mostrando a GUI para o usuário o mais rápido possível.
        timerObj
        jsBackDoor

        % Janela de progresso já criada no DOM. Dessa forma, controla-se 
        % apenas a sua visibilidade - e tornando desnecessário criá-la a
        % cada chamada (usando uiprogressdlg, por exemplo).
        progressDialog

        stableVersion
    end


    properties (Access = private)
        %-----------------------------------------------------------------%
        DefaultValues = struct('Graphics',  struct('openGL', 'hardware', 'Format', 'jpeg', 'Resolution', '120', 'Dock', true),                                                                                                                                                                                                        ...
                               'Elevation', struct('Points', '256', 'ForceSearch', false, 'Server', 'Open-Elevation'))
    end


    methods
        %-----------------------------------------------------------------%
        % IPC: COMUNICAÇÃO ENTRE PROCESSOS
        %-----------------------------------------------------------------%
        function ipcSecundaryJSEventsHandler(app, event)
            try
                switch event.HTMLEventName
                    case 'renderer'
                        startup_Controller(app)
                    otherwise
                        error('UnexpectedEvent')
                end

            catch ME
                appUtil.modalWindow(app.UIFigure, 'error', ME.message);
            end
        end
    end
    

    methods (Access = private)
        %-----------------------------------------------------------------%
        % JSBACKDOOR
        %-----------------------------------------------------------------%
        function jsBackDoor_Initialization(app)
            app.jsBackDoor = uihtml(app.UIFigure, "HTMLSource",           appUtil.jsBackDoorHTMLSource(),                 ...
                                                  "HTMLEventReceivedFcn", @(~, evt)ipcSecundaryJSEventsHandler(app, evt), ...
                                                  "Visible",              "off");
        end

        %-----------------------------------------------------------------%
        function jsBackDoor_Customizations(app)
            if app.isDocked
                app.progressDialog = app.mainApp.progressDialog;
            else
                sendEventToHTMLSource(app.jsBackDoor, 'startup', app.mainApp.executionMode);
                app.progressDialog = ccTools.ProgressDialog(app.jsBackDoor);
            end

            elToModify = {app.versionInfo};
            elDataTag  = ui.CustomizationBase.getElementsDataTag(elToModify);
            if ~isempty(elDataTag)
                appName = class(app);
                ui.TextView.startup(app.jsBackDoor, elToModify{1}, appName);
            end
        end
    end


    methods (Access = private)
        %-----------------------------------------------------------------%
        function startup_timerCreation(app)
            app.timerObj = timer("ExecutionMode", "fixedSpacing", ...
                                 "StartDelay",    1.5,            ...
                                 "Period",        .1,             ...
                                 "TimerFcn",      @(~,~)app.startup_timerFcn);
            start(app.timerObj)
        end

        %-----------------------------------------------------------------%
        function startup_timerFcn(app)
            if ccTools.fcn.UIFigureRenderStatus(app.UIFigure)
                stop(app.timerObj)
                delete(app.timerObj)
                
                jsBackDoor_Initialization(app)
            end
        end

        %-----------------------------------------------------------------%
        function startup_Controller(app)
            drawnow
            jsBackDoor_Customizations(app)

            startup_GUIComponents(app)
        end

        %-----------------------------------------------------------------%
        function startup_GUIComponents(app)
            switch app.mainApp.executionMode
                case 'webApp'
                    delete(app.openDevTools)
                otherwise
                    app.btnFolder.Enable               = 1;
                    app.versionInfoRefresh.Enable      = 1;
                    app.openAuxiliarAppAsDocked.Enable = 1;
            end

            if ~isdeployed
                app.openAuxiliarApp2Debug.Enable = 1;
            end

            General_updatePanel(app)
            Analysis_updatePanel(app)
            Folder_updatePanel(app)
        end

        %-----------------------------------------------------------------%
        function General_updatePanel(app)
            % Versão:
            ui.TextView.update(app.versionInfo, util.HtmlTextGenerator.AppInfo(app.mainApp.General, app.mainApp.rootFolder, app.mainApp.executionMode));

            % Modo de operação:
            app.openAuxiliarAppAsDocked.Value   = app.mainApp.General.operationMode.Dock;
            app.openAuxiliarApp2Debug.Value     = app.mainApp.General.operationMode.Debug;
        end

        %-----------------------------------------------------------------%
        function Analysis_updatePanel(app)
            app.EncodingDropDown_2.Items = app.mainApp.General.sped.encoding.options;
        end

        %-----------------------------------------------------------------%
        function Folder_updatePanel(app)
            % Na versão webapp, a configuração das pastas não é habilitada.

            if ~strcmp(app.mainApp.executionMode, 'webApp')
                app.btnFolder.Enable      = 1;

                DataHub_POST = app.mainApp.General.fileFolder.DataHub_POST;    
                if isfolder(DataHub_POST)
                    app.DataHubPOST.Value = DataHub_POST;
                end

                app.userPath.Value        = app.mainApp.General.fileFolder.userPath;
            end
        end

        %-----------------------------------------------------------------%
        function saveGeneralSettings(app)
            appUtil.generalSettingsSave(class.Constants.appName, app.rootFolder, app.mainApp.General_I, app.mainApp.executionMode)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp)
            
            % A razão de ser deste app é possibilitar visualização/edição 
            % de algumas das informações do arquivo "GeneralSettings.json".
            app.mainApp    = mainApp;
            app.rootFolder = mainApp.rootFolder;

            if app.isDocked
                app.GridLayout.Padding(4) = 19;
                app.jsBackDoor = mainApp.jsBackDoor;
                startup_Controller(app)
            else
                appUtil.winPosition(app.UIFigure)
                startup_timerCreation(app)
            end
            
        end

        % Close request function: UIFigure
        function closeFcn(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'closeFcn')
            delete(app)
            
        end

        % Selection changed function: LeftPanelRadioGroup
        function RadioButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.LeftPanelRadioGroup.SelectedObject;
            switch selectedButton
                case app.btnGeneral;  app.GridLayout.ColumnWidth(4:6) = {'1x',0,0};
                case app.btnAnalysis; app.GridLayout.ColumnWidth(4:6) = {0,'1x',0};
                case app.btnFolder;   app.GridLayout.ColumnWidth(4:6) = {0,0,'1x'};
            end
            
        end

        % Image clicked function: versionInfoRefresh
        function AppVersion_refreshButtonPushed(app, event)
            
            app.progressDialog.Visible = 'visible';

            [htmlContent, app.stableVersion] = util.HtmlTextGenerator.checkAvailableUpdate(app.mainApp.General, app.rootFolder);
            appUtil.modalWindow(app.UIFigure, "info", htmlContent);       

            app.progressDialog.Visible = 'hidden';

        end

        % Value changed function: openAuxiliarApp2Debug, 
        % ...and 1 other component
        function Graphics_ParameterValueChanged(app, event)
            
            switch event.Source
                case app.openAuxiliarAppAsDocked
                    app.mainApp.General.operationMode.Dock  = app.openAuxiliarAppAsDocked.Value;

                case app.openAuxiliarApp2Debug
                    app.mainApp.General.operationMode.Debug = app.openAuxiliarApp2Debug.Value;
            end

            app.mainApp.General_I.openGL        = app.mainApp.General.openGL;
            app.mainApp.General_I.operationMode = app.mainApp.General.operationMode;

            saveGeneralSettings(app)

        end

        % Image clicked function: DataHubPOSTButton, userPathButton
        function Folder_ButtonPushed(app, event)
            
            try
                relatedFolder = eval(sprintf('app.config_Folder_%s.Value', event.Source.Tag));                    
            catch
                relatedFolder = app.mainApp.General.fileFolder.(event.Source.Tag);
            end
            
            if isfolder(relatedFolder)
                initialFolder = relatedFolder;
            elseif isfile(relatedFolder)
                initialFolder = fileparts(relatedFolder);
            else
                initialFolder = app.userPath.Value;
            end
            
            selectedFolder = uigetdir(initialFolder);
            figure(app.UIFigure)

            if selectedFolder
                switch event.Source
                    case app.DataHubPOSTButton
                        if strcmp(app.mainApp.General.fileFolder.DataHub_POST, selectedFolder) 
                            return
                        else
                            selectedFolderFiles = dir(selectedFolder);
                            if ~ismember('.monitorsped_post', {selectedFolderFiles.name})
                                appUtil.modalWindow(app.UIFigure, 'error', 'Não se trata da pasta "DataHub - POST", do monitorSPED.');
                                return
                            end

                            app.DataHubPOST.Value = selectedFolder;
                            app.mainApp.General.fileFolder.DataHub_POST = selectedFolder;
    
                            ipcMainMatlabCallsHandler(app.mainApp, app, 'checkDataHubLampStatus')
                        end

                    case app.userPathButton
                        app.userPath.Value = selectedFolder;
                        app.mainApp.General.fileFolder.userPath = selectedFolder;
                end

                app.mainApp.General_I.fileFolder = app.mainApp.General.fileFolder;
                saveGeneralSettings(app)
                Folder_updatePanel(app)
            end

        end

        % Image clicked function: openDevTools
        function openDevToolsClicked(app, event)
            
            ipcMainMatlabCallsHandler(app.mainApp, app, 'openDevTools')

        end

        % Value changed function: EncodingDropDown_2
        function EncodingDropDown_2ValueChanged(app, event)
                        
            app.mainApp.General_I.sped.encoding.value = app.EncodingDropDown_2.Value;
            saveGeneralSettings(app)

        end

        % Button pushed function: Run
        function RunButtonPushed(app, event)
            
            try
                entry = strtrim(strjoin(app.Input.Value, '\n'));
                if ~isempty(entry)
                    switch entry
                        case 'clc'
                            app.Output.Value = '';
                            % app.Label.Text = ' ';

                        otherwise
                            result = evalc(entry);
                            if ~isempty(result)
                                outputValue = sprintf('%s\n%s\n%s', strjoin(app.Output.Value, '\n'), entry, result);

                                app.Output.Value = outputValue;
                                % app.Label.Text = ['<span style="font-size:Monospaced; font-size:14px;">' replace(outputValue, newline, sprintf('<br>\n')) '</span>'];

                                if ~isempty(app.Output.Value) && iscell(app.Output.Value)
                                    idxEmptyRows = cellfun(@(x) isempty(x), app.Output.Value);
                                    app.Output.Value(idxEmptyRows) = [];
                                end
                                scroll(app.Output, 'bottom')
                            end
                    end
                end

            catch ME
                uialert(app.UIFigure, ME.message, '', 'Icon', 'none')
            end

        end

        % Button pushed function: CommandTest1, CommandTest2, 
        % ...and 3 other components
        function isdeployedButtonPushed(app, event)
            
            try
                entry = event.Source.Text;
                app.Output.Value = sprintf('%s\n%s', entry, evalc(entry));
            catch ME
                uialert(app.UIFigure, ME.message, '', 'Icon', 'none')
            end

        end

        % Button pushed function: XButton
        function XButtonPushed(app, event)
            
            app.Output.Value = '';

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'monitorSPED';
            app.UIFigure.Icon = 'icon_48.png';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @closeFcn, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.toolGrid = uigridlayout(app.GridLayout);
            app.openDevTools = uiimage(app.toolGrid);
            app.openDevTools.ImageClickedFcn = createCallbackFcn(app, @openDevToolsClicked, true);
            app.openDevTools.Tooltip = {'DevTools'};
            app.openDevTools.ImageSource = 'Debug_18.png';
            app.menu_ButtonGrid = uigridlayout(app.GridLayout);
            app.menu_ButtonLabel = uilabel(app.menu_ButtonGrid);
            app.menu_ButtonLabel.Text = 'CONFIGURAÇÕES';
            app.menu_ButtonIcon = uiimage(app.menu_ButtonGrid);
            app.menu_ButtonIcon.Tag = '1';
            app.menu_ButtonIcon.ImageSource = 'Settings_18.png';
            app.menuUnderline = uiimage(app.GridLayout);
            app.menuUnderline.ImageSource = 'LineH.svg';
            app.LeftPanel = uipanel(app.GridLayout);
            app.LeftPanelGrid = uigridlayout(app.LeftPanel);
            app.LeftPanelRadioGroup = uibuttongroup(app.GridLayout);
            app.LeftPanelRadioGroup.SelectionChangedFcn = createCallbackFcn(app, @RadioButtonGroupSelectionChanged, true);
            app.btnGeneral = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnGeneral.Text = 'Aspectos gerais';
            app.btnGeneral.Interpreter = 'html';
            app.btnGeneral.Value = true;
            app.btnAnalysis = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnAnalysis.Text = 'Análise';
            app.btnAnalysis.Interpreter = 'html';
            app.btnFolder = uiradiobutton(app.LeftPanelRadioGroup);
            app.btnFolder.Enable = 'off';
            app.btnFolder.Text = 'Mapeamento de pastas';
            app.btnFolder.Interpreter = 'html';
            app.General_Grid = uigridlayout(app.GridLayout);
            app.versionInfoLabel = uilabel(app.General_Grid);
            app.versionInfoLabel.Text = 'ASPECTOS GERAIS';
            app.versionInfoRefresh = uiimage(app.General_Grid);
            app.versionInfoRefresh.ImageClickedFcn = createCallbackFcn(app, @AppVersion_refreshButtonPushed, true);
            app.versionInfoRefresh.Enable = 'off';
            app.versionInfoRefresh.Tooltip = {'Verifica atualizações'};
            app.versionInfoRefresh.ImageSource = 'Refresh_18.png';
            app.versionInfo = uilabel(app.General_Grid);
            app.versionInfo.Interpreter = 'html';
            app.versionInfo.Text = '';
            app.openAuxiliarAppAsDocked = uicheckbox(app.General_Grid);
            app.openAuxiliarAppAsDocked.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.openAuxiliarAppAsDocked.Enable = 'off';
            app.openAuxiliarAppAsDocked.Text = 'Modo DOCK: módulos auxiliares abertos na janela principal do app';
            app.openAuxiliarApp2Debug = uicheckbox(app.General_Grid);
            app.openAuxiliarApp2Debug.ValueChangedFcn = createCallbackFcn(app, @Graphics_ParameterValueChanged, true);
            app.openAuxiliarApp2Debug.Enable = 'off';
            app.openAuxiliarApp2Debug.Text = 'Modo DEBUG';
            app.CustomPlotGrid = uigridlayout(app.GridLayout);
            app.general_FileLabel = uilabel(app.CustomPlotGrid);
            app.general_FileLabel.Text = 'LEITURA DE ARQUIVOS';
            app.general_FilePanel = uipanel(app.CustomPlotGrid);
            app.GridLayout_2 = uigridlayout(app.general_FilePanel);
            app.Input = uitextarea(app.GridLayout_2);
            app.Run = uibutton(app.GridLayout_2, 'push');
            app.Run.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.Run.Text = '>>';
            app.Output = uitextarea(app.GridLayout_2);
            app.CommandTest3 = uibutton(app.GridLayout_2, 'push');
            app.CommandTest3.ButtonPushedFcn = createCallbackFcn(app, @isdeployedButtonPushed, true);
            app.CommandTest3.Text = 'struct(struct(struct(app.UIFigure).Controller).PlatformHost)';
            app.CommandTest2 = uibutton(app.GridLayout_2, 'push');
            app.CommandTest2.ButtonPushedFcn = createCallbackFcn(app, @isdeployedButtonPushed, true);
            app.CommandTest2.Text = 'struct(struct(app.UIFigure).Controller).PeerModelInfo';
            app.XButton = uibutton(app.GridLayout_2, 'push');
            app.XButton.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.XButton.Text = 'X';
            app.ctfrootButton = uibutton(app.GridLayout_2, 'push');
            app.ctfrootButton.ButtonPushedFcn = createCallbackFcn(app, @isdeployedButtonPushed, true);
            app.ctfrootButton.Text = 'ctfroot';
            app.isdeployedButton = uibutton(app.GridLayout_2, 'push');
            app.isdeployedButton.ButtonPushedFcn = createCallbackFcn(app, @isdeployedButtonPushed, true);
            app.isdeployedButton.Text = 'isdeployed';
            app.TabGroup = uitabgroup(app.GridLayout_2);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.ButtonGroup = uibuttongroup(app.GridLayout_2);
            app.ButtonGroup.Title = 'Button Group';
            app.Button = uiradiobutton(app.ButtonGroup);
            app.Button.Text = 'Button';
            app.Button.Value = true;
            app.Button2 = uiradiobutton(app.ButtonGroup);
            app.Button2.Text = 'Button2';
            app.Button3 = uiradiobutton(app.ButtonGroup);
            app.Button3.Text = 'Button3';
            app.CommandTest1 = uibutton(app.GridLayout_2, 'push');
            app.CommandTest1.ButtonPushedFcn = createCallbackFcn(app, @isdeployedButtonPushed, true);
            app.CommandTest1.Text = 'struct(struct(struct(app.UIFigure).Controller).PlatformHost).CEF.openDevTools()';
            app.EncodingDropDown_2 = uidropdown(app.GridLayout_2);
            app.EncodingDropDown_2.Items = {};
            app.EncodingDropDown_2.ValueChangedFcn = createCallbackFcn(app, @EncodingDropDown_2ValueChanged, true);
            app.EncodingDropDown_2.Value = {};
            app.EncodingLabel = uilabel(app.GridLayout_2);
            app.EncodingLabel.Text = 'Encoding:';
            app.Folders_Grid = uigridlayout(app.GridLayout);
            app.config_FolderMapLabel = uilabel(app.Folders_Grid);
            app.config_FolderMapLabel.Text = 'MAPEAMENTO DE PASTAS';
            app.FolderMapPanel = uipanel(app.Folders_Grid);
            app.FolderMapGrid = uigridlayout(app.FolderMapPanel);
            app.DataHubPOSTLabel = uilabel(app.FolderMapGrid);
            app.DataHubPOSTLabel.Text = 'DataHub - POST:';
            app.DataHubPOST = uieditfield(app.FolderMapGrid, 'text');
            app.DataHubPOST.Editable = 'off';
            app.DataHubPOSTButton = uiimage(app.FolderMapGrid);
            app.DataHubPOSTButton.ImageClickedFcn = createCallbackFcn(app, @Folder_ButtonPushed, true);
            app.DataHubPOSTButton.Tag = 'DataHub_POST';
            app.DataHubPOSTButton.ImageSource = 'OpenFile_36x36.png';
            app.userPathLabel = uilabel(app.FolderMapGrid);
            app.userPathLabel.Text = 'Pasta do usuário:';
            app.userPath = uieditfield(app.FolderMapGrid, 'text');
            app.userPath.Editable = 'off';
            app.userPathButton = uiimage(app.FolderMapGrid);
            app.userPathButton.ImageClickedFcn = createCallbackFcn(app, @Folder_ButtonPushed, true);
            app.userPathButton.Tag = 'userPath';
            app.userPathButton.ImageSource = 'OpenFile_36x36.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = winConfig(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end